classdef Farhan_app_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        Menu3_2                         matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        ManualMenu                      matlab.ui.container.Menu
        InitialConditionsandControlsPanel  matlab.ui.container.Panel
        PauseButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        StepButton                      matlab.ui.control.Button
        StartResumeButton               matlab.ui.control.StateButton
        BoundaryConditionsButtonGroup   matlab.ui.container.ButtonGroup
        PeriodicButton                  matlab.ui.control.RadioButton
        DirichletButton                 matlab.ui.control.RadioButton
        NeumannButton                   matlab.ui.control.RadioButton
        NoiseAmplitudeSlider            matlab.ui.control.RangeSlider
        NoiseAmplitudeSliderLabel       matlab.ui.control.Label
        vBaseValueEditField             matlab.ui.control.NumericEditField
        vBaseValueEditFieldLabel        matlab.ui.control.Label
        uBaseValueEditField             matlab.ui.control.NumericEditField
        uBaseValueEditFieldLabel        matlab.ui.control.Label
        InitialConditionDropDown        matlab.ui.control.DropDown
        InitialConditionDropDownLabel   matlab.ui.control.Label
        AdvancedFeaturesPanel           matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        ComplexPatternsButton           matlab.ui.control.RadioButton
        HexagonsButton                  matlab.ui.control.RadioButton
        StripesButton                   matlab.ui.control.RadioButton
        TuringSpotsButton               matlab.ui.control.RadioButton
        EnablerealtimeupdatesCheckBox   matlab.ui.control.CheckBox
        AutoscalecolorlimitsCheckBox    matlab.ui.control.CheckBox
        ColormapDropDown                matlab.ui.control.DropDown
        ColormapDropDownLabel           matlab.ui.control.Label
        PlottypeDropDown                matlab.ui.control.DropDown
        PlottypeDropDownLabel           matlab.ui.control.Label
        VariabletodisplayDropDown       matlab.ui.control.DropDown
        VariabletodisplayDropDownLabel  matlab.ui.control.Label
        ExportandStatusPanel            matlab.ui.container.Panel
        ResumeButton                    matlab.ui.control.Button
        LoopAnimationCheckBox           matlab.ui.control.CheckBox
        FrameRateSpinner                matlab.ui.control.Spinner
        FrameRateSpinnerLabel           matlab.ui.control.Label
        LoadConfigButton                matlab.ui.control.Button
        SaveConfigButton                matlab.ui.control.Button
        ExportAnimationButton           matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        CurrenttimeLabel                matlab.ui.control.Label
        StatusLabel                     matlab.ui.control.Label
        SimulationProgressGauge         matlab.ui.control.LinearGauge
        SimulationProgressGaugeLabel    matlab.ui.control.Label
        VisualizationPanel              matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        ModelandParametersPanel         matlab.ui.container.Panel
        ParameterbSliderLabel           matlab.ui.control.Label
        ParameteraSliderLabel           matlab.ui.control.Label
        ParameteraSlider                matlab.ui.control.Slider
        UpdateEveryNstepsSpinner        matlab.ui.control.Spinner
        UpdateEveryNstepsSpinnerLabel   matlab.ui.control.Label
        TotalTimeEditField              matlab.ui.control.NumericEditField
        TotalTimeEditFieldLabel         matlab.ui.control.Label
        TimeStepdtEditField             matlab.ui.control.NumericEditField
        TimeStepdtEditFieldLabel        matlab.ui.control.Label
        GridPointsNSpinner              matlab.ui.control.Spinner
        GridPointsNSpinnerLabel         matlab.ui.control.Label
        DomainSIzeEditField             matlab.ui.control.NumericEditField
        DomainSIzeEditFieldLabel        matlab.ui.control.Label
        DvSpinner                       matlab.ui.control.Spinner
        DvSpinnerLabel                  matlab.ui.control.Label
        DuSpinner                       matlab.ui.control.Spinner
        ParameterbSlider                matlab.ui.control.Slider
        DuSpinnerLabel                  matlab.ui.control.Label
        ModelTypeDropDown               matlab.ui.control.DropDown
        ModelTypeDropDownLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        % ==== Backend (OOPDELight FEM) ====
        model
        fem
        geo
    
        % Cached matrices/vectors
        K; M; Q; G; F;
        H; R;          % for Dirichlet constraints
        A; Cvec;
        n
    
        % State
        u; v
        t double = 0
        k double = 0
    
        % Timer
        timerObj timer
    end
    
    methods (Access = private)
        
        % =========================
        % Backend helpers (MUST exist)
        % =========================

        function resetDashboardUI(app)
            % Stop any running sim
            if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj);
            end
            app.StartResumeButton.Value = false;
        
            % ---- UI defaults (choose what you want as "Reset") ----
            app.ModelTypeDropDown.Items = {'Schnakenberg','Brusselator'};
            app.ModelTypeDropDown.Value = 'Schnakenberg';
        
            app.ParameteraSlider.Limits = [0.0 1.0];
            app.ParameterbSlider.Limits = [0.0 2.0];

            app.ParameteraSlider.Value  = 0.1;   % scalar
            app.ParameterbSlider.Value  = 0.9;   % scalar

            app.ParameteraSliderLabel.Text = 'a (Schnakenberg)';
            app.ParameterbSliderLabel.Text = 'b (Schnakenberg)';
        
            app.DomainSIzeEditField.Value = 1;
            app.GridPointsNSpinner.Value  = 100;
            app.TimeStepdtEditField.Value = 0.01;
            app.TotalTimeEditField.Value  = 100;
            app.UpdateEveryNstepsSpinner.Value = 10;
        
            app.uBaseValueEditField.Value = 1;
            app.vBaseValueEditField.Value = 1;
        
            app.NoiseAmplitudeSlider.Value = [0 1];
            app.InitialConditionDropDown.Items = {'Uniform','Random','Localized Spot'};
            app.InitialConditionDropDown.Value = 'Random';
        
            app.VariabletodisplayDropDown.Items = {'u','v','Both (u+v)','Magnitude'};
            app.VariabletodisplayDropDown.Value = 'u';
        
            app.PlottypeDropDown.Items = {'2D Heatmap','3D Surface','Contour'};
            app.PlottypeDropDown.Value = '2D Heatmap';
        
            app.ColormapDropDown.Items = {'parula','jet','hot','cool','turbo','gray'};
            app.ColormapDropDown.Value = 'turbo';
        
            app.EnablerealtimeupdatesCheckBox.Value = true;
            app.AutoscalecolorlimitsCheckBox.Value = true;
        
            % Boundary default
            app.NeumannButton.Value   = true;
            app.DirichletButton.Value = false;
            app.PeriodicButton.Value  = false;
        
            % Fix Du enable (grey code me off hai)
            app.DuSpinner.Enable = 'on';
            app.DuSpinnerLabel.Enable = 'on';
        
            % Status + progress + axes clear
            app.t = 0; app.k = 0;
            app.StatusLabel.Text = 'Ready.';
            app.CurrenttimeLabel.Text = 't = 0';
            app.SimulationProgressGauge.Value = 0;
        
            cla(app.UIAxes);
            title(app.UIAxes,'');
            drawnow;
        end

        
        function resetSimulation(app)
            % ---- Fix broken dropdown strings coming from createComponents (grey) ----
            app.ModelTypeDropDown.Items = {'Schnakenberg','Brusselator'};
            if ~ismember(app.ModelTypeDropDown.Value, app.ModelTypeDropDown.Items)
                app.ModelTypeDropDown.Value = 'Schnakenberg';
            end
        
            % ---- Build model ----
            modelName = app.ModelTypeDropDown.Value;
            app.model = setModel(modelName);   % uses your existing file setModel.m
        
            % Read parameters (RangeSlider -> take first value)
            a = app.ParameteraSlider.Value(1);
            b = app.ParameterbSlider.Value(1);
        
            % Push parameters into model (handle both struct/object safely)
            setModelField('Au', a);
            setModelField('Av', b);
            setModelField('dt', app.TimeStepdtEditField.Value);
            setModelField('Du', app.DuSpinner.Value);
            setModelField('Dv', app.DvSpinner.Value);
        
            % ---- Geometry + FEM ----
            L  = app.DomainSIzeEditField.Value;
            Lx = 2*L; Ly = 1*L;
        
            N = max(50, round(app.GridPointsNSpinner.Value));
            h = max(1e-6, L / max(1,(sqrt(N)-1)));
            setModelField('h', h);
        
            app.fem = Bilinear2D;
            app.geo = RectangleR(0, Lx, 0, Ly, h);
        
            % n points
            if isprop(app.geo,'nPoints')
                app.n = app.geo.nPoints;
            else
                app.n = length(app.geo.x);
            end
        
            % Boundary matrix
            if app.DirichletButton.Value
                app.geo.makeBoundaryMatrix(app.geo.dirichletBC('0'));
            elseif app.PeriodicButton.Value
                % If you have periodic support in your FEM/geo, plug it here.
                % Otherwise keep Robin(0,1) as neutral.
                app.geo.makeBoundaryMatrix(app.geo.robinBC(0,1));
            else
                app.geo.makeBoundaryMatrix(app.geo.robinBC(0,1)); % Neumann-ish safe default
            end
        
            % Assemble
            [app.K, app.M, app.F] = app.fem.assema(app.geo, 1, 1, 1);
            [app.Q, app.G, app.H, app.R] = app.fem.assemb(app.geo);
        
            % ---- Initial conditions ----
            u0 = app.uBaseValueEditField.Value * ones(app.n,1);
            v0 = app.vBaseValueEditField.Value * ones(app.n,1);
        
            ic  = lower(string(app.InitialConditionDropDown.Value));
            amp = app.NoiseAmplitudeSlider.Value(2);
        
            x = app.geo.x; y = app.geo.y;
            if contains(ic,"random")
                u0 = u0 + amp*(rand(app.n,1)-0.5);
                v0 = v0 + amp*(rand(app.n,1)-0.5);
            elseif contains(ic,"spot")
                cx = 0.5*(min(x)+max(x));
                cy = 0.5*(min(y)+max(y));
                p = zeros(app.n,1);
                p(((x-cx).^2 + (y-cy).^2) <= (0.08*L)^2) = 1;
                u0 = u0 + p;
            end
        
            app.u = u0;
            app.v = v0;
            app.t = 0;
            app.k = 0;
        
            % ---- Build system matrix A and vector Cvec (generic, based on your setModel fields) ----
            dt = getModelField('dt', app.TimeStepdtEditField.Value);
            Du = getModelField('Du', app.DuSpinner.Value);
            Dv = getModelField('Dv', app.DvSpinner.Value);
        
            % reaction linear coeffs (fallbacks if your setModel differs)
            Buu = getModelField('Buu', 0);
            Buv = getModelField('Buv', 0);
            Bvu = getModelField('Bvu', 0);
            Bvv = getModelField('Bvv', 0);
            Cu  = getModelField('Cu',  1);
            Cv  = getModelField('Cv', -1);
        
            Au  = getModelField('Au', a);
            Av  = getModelField('Av', b);
        
            s = 0; % ignore boundary source unless you use it
            app.A = [ app.M - dt*(Buu*app.M - Du*app.K + s*app.Q),   -dt*Buv*app.M;
                     -dt*Bvu*app.M,                                  app.M - dt*(Bvv*app.M - Dv*app.K + s*app.Q)];
        
            app.Cvec = dt * [ (Au*app.F + s*app.G);
                              (Av*app.F + s*app.G) ];
        
            % Dirichlet penalty
            if app.DirichletButton.Value && ~isempty(app.H)
                pen = 1e2;
                P = (app.H' * app.H);
                Z = sparse(size(P,1), size(P,2));
                app.A = app.A + pen * [P Z; Z P];
            end
        
            app.StatusLabel.Text = 'Ready.';
            app.CurrenttimeLabel.Text = 't = 0';
            app.SimulationProgressGauge.Value = 0;
        
            renderFrame(app);
        
            % ---- small helpers (inside method is OK) ----
            function setModelField(fname, val)
                if isstruct(app.model)
                    app.model.(fname) = val;
                else
                    try app.model.(fname) = val; catch, end
                end
            end
            function v = getModelField(fname, defaultV)
                v = defaultV;
                if isstruct(app.model) && isfield(app.model,fname)
                    v = app.model.(fname); return;
                end
                if ~isstruct(app.model)
                    try v = app.model.(fname); catch, v = defaultV; end
                end
            end
        end
        
        function onTick(app)
            tEnd = app.TotalTimeEditField.Value;
            if app.t >= tEnd
                StopButtonPushed(app, []);   % uses your existing callback
                app.StatusLabel.Text = 'Finished.';
                return;
            end
        
            doOneBatch(app);
        
            if app.EnablerealtimeupdatesCheckBox.Value
                renderFrame(app);
            end
        end
        
        function doOneBatch(app)
            nSteps = max(1, round(app.UpdateEveryNstepsSpinner.Value));
            dt = getDT();
        
            for ii = 1:nSteps
                % Nonlinear term (common form)
                nl = app.u.^2 .* app.v;
        
                rhs = [app.M*app.u;
                       app.M*app.v];
        
                Cu = getModelField('Cu',  1);
                Cv = getModelField('Cv', -1);
        
                NL = dt * [ Cu * (app.M*nl);
                            Cv * (app.M*nl) ];
        
                y = app.A \ (rhs + app.Cvec + NL);
        
                app.u = y(1:app.n);
                app.v = y(app.n+1:end);
        
                app.t = app.t + dt;
                app.k = app.k + 1;
        
                if app.t >= app.TotalTimeEditField.Value
                    break;
                end
            end
        
            app.CurrenttimeLabel.Text = sprintf('t = %.4g', app.t);
            app.SimulationProgressGauge.Value = min(1, app.t/max(app.TotalTimeEditField.Value, eps));
        
            function dt = getDT()
                if isstruct(app.model) && isfield(app.model,'dt'), dt = app.model.dt;
                else
                    try dt = app.model.dt; catch, dt = app.TimeStepdtEditField.Value; end
                end
            end
            function v = getModelField(fname, defaultV)
                v = defaultV;
                if isstruct(app.model) && isfield(app.model,fname)
                    v = app.model.(fname); return;
                end
                if ~isstruct(app.model)
                    try v = app.model.(fname); catch, v = defaultV; end
                end
            end
        end
        
        function renderFrame(app)
    if isempty(app.geo) || isempty(app.u)
        return;
    end

    % --- choose what to plot (u/v/both/magnitude) ---
    var = lower(string(app.VariabletodisplayDropDown.Value));
    if var == "v"
        z = app.v;
    elseif contains(var,"both")
        z = app.u + app.v;
    elseif contains(var,"magnitude")
        z = sqrt(app.u.^2 + app.v.^2);
    else
        z = app.u;
    end

    cla(app.UIAxes);

    % --- plot type switch (like simulatorGUI style) ---
    ptype = app.PlottypeDropDown.Value;

    switch ptype
        case '2D Heatmap'
            % simulatorGUI-like mesh plot
            if ismethod(app.geo,'plot')
                app.geo.plot(app.UIAxes, z, 'EdgeColor','none');
            else
                scatter(app.UIAxes, app.geo.x, app.geo.y, 12, z, 'filled');
            end
            view(app.UIAxes, 2);

        case '3D Surface'
            % 3D mesh surface (needs triangulation)
            if isprop(app.geo,'t') && ~isempty(app.geo.t)
                trisurf(app.geo.t, app.geo.x, app.geo.y, z, ...
                    'Parent', app.UIAxes, 'EdgeColor','none');
                view(app.UIAxes, 3);
            else
                % fallback
                scatter(app.UIAxes, app.geo.x, app.geo.y, 12, z, 'filled');
                view(app.UIAxes, 2);
            end

        case 'Contour'
            % Contour on triangulation (safe fallback if tricontourf not available)
            if isprop(app.geo,'t') && ~isempty(app.geo.t)
                tri = triangulation(app.geo.t, app.geo.x, app.geo.y);
                % Create a grid for contouring using scatteredInterpolant
                F = scatteredInterpolant(tri.Points(:,1), tri.Points(:,2), z, 'natural', 'none');
                xmin = min(app.geo.x); xmax = max(app.geo.x);
                ymin = min(app.geo.y); ymax = max(app.geo.y);
                [X,Y] = meshgrid(linspace(xmin,xmax,150), linspace(ymin,ymax,150));
                Z = F(X,Y);
                contourf(app.UIAxes, X, Y, Z, 20, 'LineStyle','none');
                view(app.UIAxes, 2);
            else
                scatter(app.UIAxes, app.geo.x, app.geo.y, 12, z, 'filled');
                view(app.UIAxes, 2);
            end

        otherwise
            % fallback
            if ismethod(app.geo,'plot')
                app.geo.plot(app.UIAxes, z, 'EdgeColor','none');
            else
                scatter(app.UIAxes, app.geo.x, app.geo.y, 12, z, 'filled');
            end
            view(app.UIAxes, 2);
    end

    axis(app.UIAxes,'tight');

    % colormap + colorbar
    try
        colormap(app.UIAxes, app.ColormapDropDown.Value);
    catch
        colormap(app.UIAxes, 'parula');
    end
    colorbar(app.UIAxes);

    title(app.UIAxes, sprintf('t = %.4g', app.t));
    drawnow limitrate;
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    
    % 1) Make Du editable 
    app.DuSpinner.Enable = 'on';
    app.DuSpinnerLabel.Enable = 'on';

    % 2) Safe defaults / fix dropdowns (in case designer me mismatch ho)
    app.ModelTypeDropDown.Items = {'Schnakenberg','Brusselator'};
    if ~ismember(app.ModelTypeDropDown.Value, app.ModelTypeDropDown.Items)
        app.ModelTypeDropDown.Value = 'Schnakenberg';
    end

    app.InitialConditionDropDown.Items = {'Uniform','Random','Localized Spot'};
    if ~ismember(app.InitialConditionDropDown.Value, app.InitialConditionDropDown.Items)
        app.InitialConditionDropDown.Value = 'Random';
    end

    app.VariabletodisplayDropDown.Items = {'u','v','Both (u+v)','Magnitude'};
    if ~ismember(app.VariabletodisplayDropDown.Value, app.VariabletodisplayDropDown.Items)
        app.VariabletodisplayDropDown.Value = 'u';
    end

    app.PlottypeDropDown.Items = {'2D Heatmap','3D Surface','Contour','2D Quiver'};
    if ~ismember(app.PlottypeDropDown.Value, app.PlottypeDropDown.Items)
        app.PlottypeDropDown.Value = '2D Heatmap';
    end

    app.ColormapDropDown.Items = {'parula','jet','hot','cool','turbo','viridis','gray'};
    if ~ismember(app.ColormapDropDown.Value, app.ColormapDropDown.Items)
        app.ColormapDropDown.Value = 'turbo';
    end

    % 3) Status + gauge + axes
    app.StatusLabel.Text = 'Ready.';
    app.CurrenttimeLabel.Text = 't = 0';
    app.SimulationProgressGauge.Value = 0;
    cla(app.UIAxes);
    drawnow;

    % 4) Optional: start with a fresh initial frame
    resetSimulation(app);
     
        end

        % Callback function
        function ParameterbParameterbSliderValueChanged(app, event)
    % optional: do nothing or update label etc.
    value = app.ParameterbSlider.Value;
        end

        % Value changed function: ModelTypeDropDown
        function ModelTypeDropDownValueChanged(app, event)
            app.model = app.ModelTypeDropDown.Value;       
            switch app.model
                case 'Schnakenberg'
                    app.ParameteraSlider.Limits = [0.0 1.0];
                    app.ParameterbSlider.Limits = [0.0 2.0];
                    app.ParameteraSlider.Value  = 0.1;
                    app.ParameterbSlider.Value  = 0.9;
                    app.ParameteraSliderLabel.Text = 'a (Schnakenberg)';
                    app.ParameterbSliderLabel.Text = 'b (Schnakenberg)';
    
                case 'Brusselator'
                    app.ParameteraSlider.Limits = [0.0 5.0];
                    app.ParameterbSlider.Limits = [0.0 15.0];
                    app.ParameteraSlider.Value  = 1.0;
                    app.ParameterbSlider.Value  = 3.0;
                    app.ParameteraSliderLabel.Text = 'A (Brusselator)';
                    app.ParameteraSliderLabel.Text = 'B (Brusselator)';
                otherwise
            end
        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            app.StartResumeButton.Value = true;
            StartResumeButtonValueChanged(app, event);
        end

        % Callback function
        function PauseButtonPushed(app, event)
            if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj);
            end
            app.StatusLabel.Text = 'Paused.';
            app.StartResumeButton.Value = false;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             if ~isempty(app.timerObj) && isvalid(app.timerObj)
                stop(app.timerObj);
            end
            app.StatusLabel.Text = 'Stopped.';
            app.StartResumeButton.Value = false;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            resetDashboardUI(app);   % UI reset + stop timer + clear axes etc.
            resetSimulation(app);    % backend rebuild + initial condition + first frame
            app.StatusLabel.Text = 'Reset done.';
        end

        % Button pushed function: StepButton
        function StepButtonPushed(app, event)
            if isempty(app.model) || isempty(app.geo)
                resetSimulation(app);
            end
            doOneBatch(app);
            renderFrame(app);
        end

        % Value changed function: StartResumeButton
        function StartResumeButtonValueChanged(app, event)
            if app.StartResumeButton.Value
                if isempty(app.model) || isempty(app.geo)
                    resetSimulation(app);
                end
        
                if isempty(app.timerObj) || ~isvalid(app.timerObj)
                    app.timerObj = timer( ...
                        'ExecutionMode','fixedSpacing', ...
                        'Period', max(0.01, 1/max(1, app.FrameRateSpinner.Value)), ...
                        'TimerFcn', @(~,~)onTick(app));
                else
                    app.timerObj.Period = max(0.01, 1/max(1, app.FrameRateSpinner.Value));
                end
        
                start(app.timerObj);
                app.StatusLabel.Text = 'Running...';
            else
                PauseButtonPushed(app, event);
            end
        end

        % Value changed function: PlottypeDropDown
        function PlottypeDropDownValueChanged(app, event)
          renderFrame(app);
            
        end

        % Value changed function: ParameterbSlider
        function ParameterbSliderValueChanged(app, event)
            value = app.ParameterbSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.Menu = uimenu(app.FileMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.FileMenu);
            app.Menu2.Text = 'Menu2';
            app.Menu3_2 = uimenu(app.FileMenu);
            app.Menu3_2.Text = 'Menu3';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.ManualMenu = uimenu(app.HelpMenu);
            app.ManualMenu.Text = 'Manual';
            app.ModelandParametersPanel = uipanel(app.UIFigure);
            app.ModelandParametersPanel.Title = 'Model and Parameters';
            app.ModelTypeDropDownLabel = uilabel(app.ModelandParametersPanel);
            app.ModelTypeDropDownLabel.Text = 'Model Type';
            app.ModelTypeDropDown = uidropdown(app.ModelandParametersPanel);
            app.ModelTypeDropDown.Items = {'Schnakenberg', 'Brusselator', 'Custom'};
            app.ModelTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelTypeDropDownValueChanged, true);
            app.ModelTypeDropDown.Value = 'Schnakenberg';
            app.DuSpinnerLabel = uilabel(app.ModelandParametersPanel);
            app.DuSpinnerLabel.Enable = 'off';
            app.DuSpinnerLabel.Text = 'Du';
            app.ParameterbSlider = uislider(app.ModelandParametersPanel);
            app.ParameterbSlider.Limits = [0.5 2];
            app.ParameterbSlider.ValueChangedFcn = createCallbackFcn(app, @ParameterbSliderValueChanged, true);
            app.ParameterbSlider.Value = 0.5;
            app.DuSpinner = uispinner(app.ModelandParametersPanel);
            app.DuSpinner.Step = 0.1;
            app.DuSpinner.Limits = [0.1 100];
            app.DuSpinner.Enable = 'off';
            app.DuSpinner.Value = 0.1;
            app.DvSpinnerLabel = uilabel(app.ModelandParametersPanel);
            app.DvSpinnerLabel.Text = 'Dv';
            app.DvSpinner = uispinner(app.ModelandParametersPanel);
            app.DvSpinner.Limits = [0.1 100];
            app.DvSpinner.Value = 0.1;
            app.DomainSIzeEditFieldLabel = uilabel(app.ModelandParametersPanel);
            app.DomainSIzeEditFieldLabel.Text = 'Domain SIze';
            app.DomainSIzeEditField = uieditfield(app.ModelandParametersPanel, 'numeric');
            app.DomainSIzeEditField.Limits = [1 100];
            app.DomainSIzeEditField.Value = 1;
            app.GridPointsNSpinnerLabel = uilabel(app.ModelandParametersPanel);
            app.GridPointsNSpinnerLabel.Text = 'Grid Points(N)';
            app.GridPointsNSpinner = uispinner(app.ModelandParametersPanel);
            app.GridPointsNSpinner.Step = 10;
            app.GridPointsNSpinner.Limits = [50 500];
            app.GridPointsNSpinner.Value = 100;
            app.TimeStepdtEditFieldLabel = uilabel(app.ModelandParametersPanel);
            app.TimeStepdtEditFieldLabel.Text = 'Time Step (dt)';
            app.TimeStepdtEditField = uieditfield(app.ModelandParametersPanel, 'numeric');
            app.TimeStepdtEditField.Value = 0.01;
            app.TotalTimeEditFieldLabel = uilabel(app.ModelandParametersPanel);
            app.TotalTimeEditFieldLabel.Text = 'Total Time';
            app.TotalTimeEditField = uieditfield(app.ModelandParametersPanel, 'numeric');
            app.TotalTimeEditField.Value = 100;
            app.UpdateEveryNstepsSpinnerLabel = uilabel(app.ModelandParametersPanel);
            app.UpdateEveryNstepsSpinnerLabel.Text = 'Update Every N steps';
            app.UpdateEveryNstepsSpinner = uispinner(app.ModelandParametersPanel);
            app.UpdateEveryNstepsSpinner.Limits = [1 100];
            app.UpdateEveryNstepsSpinner.Value = 10;
            app.ParameteraSlider = uislider(app.ModelandParametersPanel);
            app.ParameteraSlider.Limits = [0.01 2];
            app.ParameteraSlider.Value = 0.01;
            app.ParameteraSliderLabel = uilabel(app.ModelandParametersPanel);
            app.ParameteraSliderLabel.Text = 'Parameter a';
            app.ParameterbSliderLabel = uilabel(app.ModelandParametersPanel);
            app.ParameterbSliderLabel.Text = 'Parameter b';
            app.VisualizationPanel = uipanel(app.UIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.UIAxes = uiaxes(app.VisualizationPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ExportandStatusPanel = uipanel(app.UIFigure);
            app.ExportandStatusPanel.Title = 'Export and Status';
            app.SimulationProgressGaugeLabel = uilabel(app.ExportandStatusPanel);
            app.SimulationProgressGaugeLabel.Text = 'Simulation Progress';
            app.SimulationProgressGauge = uigauge(app.ExportandStatusPanel, 'linear');
            app.StatusLabel = uilabel(app.ExportandStatusPanel);
            app.StatusLabel.Text = 'Status';
            app.CurrenttimeLabel = uilabel(app.ExportandStatusPanel);
            app.CurrenttimeLabel.Text = 'Current time';
            app.SaveDataButton = uibutton(app.ExportandStatusPanel, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.ExportAnimationButton = uibutton(app.ExportandStatusPanel, 'push');
            app.ExportAnimationButton.Text = 'Export Animation';
            app.SaveConfigButton = uibutton(app.ExportandStatusPanel, 'push');
            app.SaveConfigButton.Text = 'Save Config';
            app.LoadConfigButton = uibutton(app.ExportandStatusPanel, 'push');
            app.LoadConfigButton.Text = 'Load Config';
            app.FrameRateSpinnerLabel = uilabel(app.ExportandStatusPanel);
            app.FrameRateSpinnerLabel.Text = 'Frame Rate';
            app.FrameRateSpinner = uispinner(app.ExportandStatusPanel);
            app.FrameRateSpinner.Limits = [1 60];
            app.FrameRateSpinner.Value = 30;
            app.LoopAnimationCheckBox = uicheckbox(app.ExportandStatusPanel);
            app.LoopAnimationCheckBox.Text = 'Loop Animation';
            app.ResumeButton = uibutton(app.ExportandStatusPanel, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.AdvancedFeaturesPanel = uipanel(app.UIFigure);
            app.AdvancedFeaturesPanel.Title = 'Advanced Features';
            app.VariabletodisplayDropDownLabel = uilabel(app.AdvancedFeaturesPanel);
            app.VariabletodisplayDropDownLabel.Text = 'Variable to display';
            app.VariabletodisplayDropDown = uidropdown(app.AdvancedFeaturesPanel);
            app.VariabletodisplayDropDown.Items = {'u', 'v', 'Both'};
            app.VariabletodisplayDropDown.Value = 'u';
            app.PlottypeDropDownLabel = uilabel(app.AdvancedFeaturesPanel);
            app.PlottypeDropDownLabel.Text = 'Plot type';
            app.PlottypeDropDown = uidropdown(app.AdvancedFeaturesPanel);
            app.PlottypeDropDown.Items = {'2D Heatmap', '3D Surface', 'Contour', '2D Quiver'};
            app.PlottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottypeDropDownValueChanged, true);
            app.PlottypeDropDown.Value = '2D Heatmap';
            app.ColormapDropDownLabel = uilabel(app.AdvancedFeaturesPanel);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.AdvancedFeaturesPanel);
            app.ColormapDropDown.Items = {'parula', 'jet', 'hot', 'cool', 'turbo', 'viridis'};
            app.ColormapDropDown.Value = 'parula';
            app.AutoscalecolorlimitsCheckBox = uicheckbox(app.AdvancedFeaturesPanel);
            app.AutoscalecolorlimitsCheckBox.Text = 'Autoscale color limits';
            app.EnablerealtimeupdatesCheckBox = uicheckbox(app.AdvancedFeaturesPanel);
            app.EnablerealtimeupdatesCheckBox.Text = 'Enable real time updates';
            app.ButtonGroup = uibuttongroup(app.AdvancedFeaturesPanel);
            app.TuringSpotsButton = uiradiobutton(app.ButtonGroup);
            app.TuringSpotsButton.Text = 'Turing Spots';
            app.TuringSpotsButton.Value = true;
            app.StripesButton = uiradiobutton(app.ButtonGroup);
            app.StripesButton.Text = 'Stripes';
            app.HexagonsButton = uiradiobutton(app.ButtonGroup);
            app.HexagonsButton.Text = 'Hexagons';
            app.ComplexPatternsButton = uiradiobutton(app.ButtonGroup);
            app.ComplexPatternsButton.Text = 'Complex Patterns';
            app.InitialConditionsandControlsPanel = uipanel(app.UIFigure);
            app.InitialConditionsandControlsPanel.Title = 'Initial Conditions and Controls';
            app.InitialConditionDropDownLabel = uilabel(app.InitialConditionsandControlsPanel);
            app.InitialConditionDropDownLabel.Text = 'Initial Condition';
            app.InitialConditionDropDown = uidropdown(app.InitialConditionsandControlsPanel);
            app.InitialConditionDropDown.Items = {'Uniform', 'Random', 'Localized Spot', 'Custom'};
            app.InitialConditionDropDown.Value = 'Uniform';
            app.uBaseValueEditFieldLabel = uilabel(app.InitialConditionsandControlsPanel);
            app.uBaseValueEditFieldLabel.Text = 'u₀ Base Value';
            app.uBaseValueEditField = uieditfield(app.InitialConditionsandControlsPanel, 'numeric');
            app.uBaseValueEditField.Value = 1;
            app.vBaseValueEditFieldLabel = uilabel(app.InitialConditionsandControlsPanel);
            app.vBaseValueEditFieldLabel.Text = 'v₀ Base Value';
            app.vBaseValueEditField = uieditfield(app.InitialConditionsandControlsPanel, 'numeric');
            app.vBaseValueEditField.Value = 1;
            app.NoiseAmplitudeSliderLabel = uilabel(app.InitialConditionsandControlsPanel);
            app.NoiseAmplitudeSliderLabel.Text = 'Noise Amplitude';
            app.NoiseAmplitudeSlider = uislider(app.InitialConditionsandControlsPanel, 'range');
            app.NoiseAmplitudeSlider.Limits = [0 1];
            app.NoiseAmplitudeSlider.Step = 0.1;
            app.NoiseAmplitudeSlider.Value = [0 1];
            app.BoundaryConditionsButtonGroup = uibuttongroup(app.InitialConditionsandControlsPanel);
            app.BoundaryConditionsButtonGroup.Title = 'Boundary Conditions';
            app.NeumannButton = uiradiobutton(app.BoundaryConditionsButtonGroup);
            app.NeumannButton.Text = 'Neumann';
            app.NeumannButton.Value = true;
            app.DirichletButton = uiradiobutton(app.BoundaryConditionsButtonGroup);
            app.DirichletButton.Text = 'Dirichlet';
            app.PeriodicButton = uiradiobutton(app.BoundaryConditionsButtonGroup);
            app.PeriodicButton.Text = 'Periodic';
            app.StartResumeButton = uibutton(app.InitialConditionsandControlsPanel, 'state');
            app.StartResumeButton.ValueChangedFcn = createCallbackFcn(app, @StartResumeButtonValueChanged, true);
            app.StartResumeButton.Text = 'Start/Resume';
            app.StepButton = uibutton(app.InitialConditionsandControlsPanel, 'push');
            app.StepButton.ButtonPushedFcn = createCallbackFcn(app, @StepButtonPushed, true);
            app.StepButton.Text = 'Step';
            app.ResetButton = uibutton(app.InitialConditionsandControlsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StopButton = uibutton(app.InitialConditionsandControlsPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.InitialConditionsandControlsPanel, 'push');
            app.PauseButton.Text = 'Pause';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Farhan_app_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end